classdef ModelExplainerApp_WebApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ModelExplainerPanel            matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        DataTab                        matlab.ui.container.Tab
        DataTable                      matlab.ui.control.Table
        ExplainerDashboardTab          matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        Panel_2                        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        DataButtonGroup                matlab.ui.container.ButtonGroup
        DataTrainButton                matlab.ui.control.RadioButton
        DataTestButton                 matlab.ui.control.RadioButton
        GridLayout4                    matlab.ui.container.GridLayout
        ObservationtoExplainEditField  matlab.ui.control.NumericEditField
        ObservationtoExplainEditFieldLabel  matlab.ui.control.Label
        GridLayout5                    matlab.ui.container.GridLayout
        RUNALLButton                   matlab.ui.control.Button
        ClearFiguresButton             matlab.ui.control.Button
        GenerateReportButton           matlab.ui.control.Button
        PDPICETab                      matlab.ui.container.Tab
        ObservationtoExplainLabelICE   matlab.ui.control.Label
        ObservationTablePDP            matlab.ui.control.Table
        Switch                         matlab.ui.control.Switch
        Panel                          matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        Predictor1DropDownLabel        matlab.ui.control.Label
        Predictor1DropDown             matlab.ui.control.DropDown
        Predictor2DropDownLabel        matlab.ui.control.Label
        Predictor2DropDown             matlab.ui.control.DropDown
        RUNButtonPDP                   matlab.ui.control.Button
        PREDICTORIMPORTANCETab         matlab.ui.container.Tab
        ObservationtoExplainLabelPredictorImportance  matlab.ui.control.Label
        ObservationTablePredictorImportance  matlab.ui.control.Table
        RUNButtonPredictorImportance   matlab.ui.control.Button
        PredictorImportanceAxes        matlab.ui.control.UIAxes
        OOBPERMUTEDPITab               matlab.ui.container.Tab
        ObservationtoExplainLabelOOB   matlab.ui.control.Label
        ObservationTableOOB            matlab.ui.control.Table
        RUNButtonOOB                   matlab.ui.control.Button
        OOBAxes                        matlab.ui.control.UIAxes
        LIMETab                        matlab.ui.container.Tab
        ObservationtoExplainLabelLIME  matlab.ui.control.Label
        ScoreEditField_2Label          matlab.ui.control.Label
        ScoreEditFieldLIME             matlab.ui.control.NumericEditField
        ExpectedEditField_2Label       matlab.ui.control.Label
        ExpectedEditFieldLIME          matlab.ui.control.NumericEditField
        ObservationTableLIME           matlab.ui.control.Table
        PredictorsSpinnerLabel         matlab.ui.control.Label
        PredictorsSpinner              matlab.ui.control.Spinner
        DistancemeasureDropDownLabel   matlab.ui.control.Label
        DistancemeasureDropDown        matlab.ui.control.DropDown
        RUNButtonLIME                  matlab.ui.control.Button
        LIMEAxes                       matlab.ui.control.UIAxes
        SHAPLEYTab                     matlab.ui.container.Tab
        ObservationTableShapley        matlab.ui.control.Table
        ObservationtoExplainLabelShapley  matlab.ui.control.Label
        ScoreEditFieldLabel            matlab.ui.control.Label
        ScoreEditFieldShapley          matlab.ui.control.NumericEditField
        ExpectedEditFieldLabel         matlab.ui.control.Label
        ExpectedEditFieldShapley       matlab.ui.control.NumericEditField
        UseParallelCheckBox            matlab.ui.control.CheckBox
        SimulationsEditFieldLabel      matlab.ui.control.Label
        SimulationsEditField           matlab.ui.control.NumericEditField
        RUNButtonShapley               matlab.ui.control.Button
        ShapleyAxes                    matlab.ui.control.UIAxes
        ACTIVATIONSTab                 matlab.ui.container.Tab
        ObservationTableActivations    matlab.ui.control.Table
        TSNETab                        matlab.ui.container.Tab
        ObservationTableTSNE           matlab.ui.control.Table
        IMAGELIMETab                   matlab.ui.container.Tab
        ObservationTableImageLime      matlab.ui.control.Table
        OCCLUSIONSENSITIVITYTab        matlab.ui.container.Tab
        OcclusionSensitivityAxes       matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [file_dataTrain,path_dataTrain] = uigetfile('*.mat', "Select a data train mat file");
            dataTrain                       = load(fullfile(path_dataTrain,file_dataTrain), "-mat");
            f                               = fields(dataTrain);
            dataTrain                       = dataTrain.(f{1});
            [file_dataTest,path_dataTest]   = uigetfile('*.mat', "Select a data test mat file");
            dataTest                        = load(fullfile(path_dataTest,file_dataTest), "-mat");
            f                               = fields(dataTest);
            dataTest                        = dataTest.(f{1});            
            [file_model,path_model]         = uigetfile('*.mat', "Select a model mat file (can be compact)");
            model                           = load(fullfile(path_model,file_model), "-mat");
            f                               = fields(model);
            model                           = model.(f{1});            
            myModel     = Model("dataTrain", dataTrain, "dataTest", dataTest, "Mdl", model);
            myView      = View("app", app, "model", myModel);
            myCallbacks = Callbacks("view", myView); %#ok<NASGU> 
            h           = findobj(app.UIFigure, '-property', 'FontName');
            arrayfun(@(x) set(x, "FontName", "AkzidenzGrotesk"), h)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Machine Learning Model Explainer';
            app.UIFigure.Tag = 'UIFigure';
            app.ModelExplainerPanel = uipanel(app.UIFigure);
            app.ModelExplainerPanel.Title = 'Model Explainer';
            app.TabGroup = uitabgroup(app.ModelExplainerPanel);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.DataTable = uitable(app.DataTab);
            app.DataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.DataTable.RowName = {};
            app.ExplainerDashboardTab = uitab(app.TabGroup);
            app.ExplainerDashboardTab.Title = 'Explainer Dashboard';
            app.GridLayout2 = uigridlayout(app.ExplainerDashboardTab);
            app.Panel_2 = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.DataButtonGroup = uibuttongroup(app.GridLayout3);
            app.DataTrainButton = uiradiobutton(app.DataButtonGroup);
            app.DataTrainButton.Text = 'DataTrain';
            app.DataTrainButton.Value = true;
            app.DataTestButton = uiradiobutton(app.DataButtonGroup);
            app.DataTestButton.Text = 'DataTest';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.ObservationtoExplainEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ObservationtoExplainEditField.Value = 1;
            app.ObservationtoExplainEditFieldLabel = uilabel(app.GridLayout4);
            app.ObservationtoExplainEditFieldLabel.Text = 'Observation to Explain';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.RUNALLButton = uibutton(app.GridLayout5, 'push');
            app.RUNALLButton.Text = 'Run ALL';
            app.ClearFiguresButton = uibutton(app.GridLayout5, 'push');
            app.ClearFiguresButton.Text = 'Clear Figures';
            app.GenerateReportButton = uibutton(app.GridLayout5, 'push');
            app.GenerateReportButton.Text = 'Generate Report';
            app.PDPICETab = uitab(app.TabGroup);
            app.PDPICETab.Title = 'PDP/ICE';
            app.ObservationtoExplainLabelICE = uilabel(app.PDPICETab);
            app.ObservationtoExplainLabelICE.Text = 'Observation to Explain';
            app.ObservationTablePDP = uitable(app.PDPICETab);
            app.ObservationTablePDP.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ObservationTablePDP.RowName = {};
            app.Switch = uiswitch(app.PDPICETab, 'slider');
            app.Switch.Items = {'PDP', 'ICE'};
            app.Switch.Value = 'PDP';
            app.Panel = uipanel(app.PDPICETab);
            app.GridLayout = uigridlayout(app.Panel);
            app.Predictor1DropDownLabel = uilabel(app.PDPICETab);
            app.Predictor1DropDownLabel.Text = 'Predictor 1';
            app.Predictor1DropDown = uidropdown(app.PDPICETab);
            app.Predictor2DropDownLabel = uilabel(app.PDPICETab);
            app.Predictor2DropDownLabel.Text = 'Predictor 2';
            app.Predictor2DropDown = uidropdown(app.PDPICETab);
            app.RUNButtonPDP = uibutton(app.PDPICETab, 'push');
            app.RUNButtonPDP.Text = 'RUN';
            app.PREDICTORIMPORTANCETab = uitab(app.TabGroup);
            app.PREDICTORIMPORTANCETab.Title = 'PREDICTOR IMPORTANCE';
            app.ObservationtoExplainLabelPredictorImportance = uilabel(app.PREDICTORIMPORTANCETab);
            app.ObservationtoExplainLabelPredictorImportance.Text = 'Observation to Explain';
            app.ObservationTablePredictorImportance = uitable(app.PREDICTORIMPORTANCETab);
            app.ObservationTablePredictorImportance.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ObservationTablePredictorImportance.RowName = {};
            app.RUNButtonPredictorImportance = uibutton(app.PREDICTORIMPORTANCETab, 'push');
            app.RUNButtonPredictorImportance.Text = 'RUN';
            app.PredictorImportanceAxes = uiaxes(app.PREDICTORIMPORTANCETab);
            title(app.PredictorImportanceAxes, 'Predictor Importance')
            xlabel(app.PredictorImportanceAxes, 'X')
            ylabel(app.PredictorImportanceAxes, 'Y')
            app.OOBPERMUTEDPITab = uitab(app.TabGroup);
            app.OOBPERMUTEDPITab.Title = 'OOBPERMUTEDPI';
            app.ObservationtoExplainLabelOOB = uilabel(app.OOBPERMUTEDPITab);
            app.ObservationtoExplainLabelOOB.Text = 'Observation to Explain';
            app.ObservationTableOOB = uitable(app.OOBPERMUTEDPITab);
            app.ObservationTableOOB.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ObservationTableOOB.RowName = {};
            app.RUNButtonOOB = uibutton(app.OOBPERMUTEDPITab, 'push');
            app.RUNButtonOOB.Text = 'RUN';
            app.OOBAxes = uiaxes(app.OOBPERMUTEDPITab);
            title(app.OOBAxes, 'Predictor Importance')
            xlabel(app.OOBAxes, 'X')
            ylabel(app.OOBAxes, 'Y')
            app.LIMETab = uitab(app.TabGroup);
            app.LIMETab.Title = 'LIME';
            app.ObservationtoExplainLabelLIME = uilabel(app.LIMETab);
            app.ObservationtoExplainLabelLIME.Text = 'Observation to Explain';
            app.ScoreEditField_2Label = uilabel(app.LIMETab);
            app.ScoreEditField_2Label.Text = 'Score';
            app.ScoreEditFieldLIME = uieditfield(app.LIMETab, 'numeric');
            app.ScoreEditFieldLIME.Editable = 'off';
            app.ExpectedEditField_2Label = uilabel(app.LIMETab);
            app.ExpectedEditField_2Label.Text = 'Expected';
            app.ExpectedEditFieldLIME = uieditfield(app.LIMETab, 'numeric');
            app.ExpectedEditFieldLIME.Editable = 'off';
            app.ObservationTableLIME = uitable(app.LIMETab);
            app.ObservationTableLIME.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ObservationTableLIME.RowName = {};
            app.PredictorsSpinnerLabel = uilabel(app.LIMETab);
            app.PredictorsSpinnerLabel.Text = 'Predictors';
            app.PredictorsSpinner = uispinner(app.LIMETab);
            app.PredictorsSpinner.Limits = [1 Inf];
            app.PredictorsSpinner.Value = 1;
            app.DistancemeasureDropDownLabel = uilabel(app.LIMETab);
            app.DistancemeasureDropDownLabel.Text = {'Distance'; 'measure'};
            app.DistancemeasureDropDown = uidropdown(app.LIMETab);
            app.DistancemeasureDropDown.Items = {'chebychev', 'euclidean'};
            app.DistancemeasureDropDown.Value = 'chebychev';
            app.RUNButtonLIME = uibutton(app.LIMETab, 'push');
            app.RUNButtonLIME.Text = 'RUN';
            app.LIMEAxes = uiaxes(app.LIMETab);
            title(app.LIMEAxes, 'LIME')
            xlabel(app.LIMEAxes, 'X')
            ylabel(app.LIMEAxes, 'Y')
            app.SHAPLEYTab = uitab(app.TabGroup);
            app.SHAPLEYTab.Title = 'SHAPLEY';
            app.ObservationTableShapley = uitable(app.SHAPLEYTab);
            app.ObservationTableShapley.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ObservationTableShapley.RowName = {};
            app.ObservationtoExplainLabelShapley = uilabel(app.SHAPLEYTab);
            app.ObservationtoExplainLabelShapley.Text = 'Observation to Explain';
            app.ScoreEditFieldLabel = uilabel(app.SHAPLEYTab);
            app.ScoreEditFieldLabel.Text = 'Score';
            app.ScoreEditFieldShapley = uieditfield(app.SHAPLEYTab, 'numeric');
            app.ScoreEditFieldShapley.Editable = 'off';
            app.ExpectedEditFieldLabel = uilabel(app.SHAPLEYTab);
            app.ExpectedEditFieldLabel.Text = 'Expected';
            app.ExpectedEditFieldShapley = uieditfield(app.SHAPLEYTab, 'numeric');
            app.ExpectedEditFieldShapley.Editable = 'off';
            app.UseParallelCheckBox = uicheckbox(app.SHAPLEYTab);
            app.UseParallelCheckBox.Text = 'Use Parallel';
            app.SimulationsEditFieldLabel = uilabel(app.SHAPLEYTab);
            app.SimulationsEditFieldLabel.Text = 'Simulations';
            app.SimulationsEditField = uieditfield(app.SHAPLEYTab, 'numeric');
            app.SimulationsEditField.Value = 100;
            app.RUNButtonShapley = uibutton(app.SHAPLEYTab, 'push');
            app.RUNButtonShapley.Text = 'RUN';
            app.ShapleyAxes = uiaxes(app.SHAPLEYTab);
            title(app.ShapleyAxes, 'SHAPley')
            xlabel(app.ShapleyAxes, 'X')
            ylabel(app.ShapleyAxes, 'Y')
            app.ACTIVATIONSTab = uitab(app.TabGroup);
            app.ACTIVATIONSTab.Title = 'ACTIVATIONS';
            app.ObservationTableActivations = uitable(app.ACTIVATIONSTab);
            app.ObservationTableActivations.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ObservationTableActivations.RowName = {};
            app.TSNETab = uitab(app.TabGroup);
            app.TSNETab.Title = 'TSNE';
            app.ObservationTableTSNE = uitable(app.TSNETab);
            app.ObservationTableTSNE.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ObservationTableTSNE.RowName = {};
            app.IMAGELIMETab = uitab(app.TabGroup);
            app.IMAGELIMETab.Title = 'IMAGELIME';
            app.ObservationTableImageLime = uitable(app.IMAGELIMETab);
            app.ObservationTableImageLime.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ObservationTableImageLime.RowName = {};
            app.OCCLUSIONSENSITIVITYTab = uitab(app.TabGroup);
            app.OCCLUSIONSENSITIVITYTab.Title = 'OCCLUSIONSENSITIVITY';
            app.OcclusionSensitivityAxes = uiaxes(app.OCCLUSIONSENSITIVITYTab);
            title(app.OcclusionSensitivityAxes, 'Occlusion Sensitivity')
            xlabel(app.OcclusionSensitivityAxes, 'X')
            ylabel(app.OcclusionSensitivityAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelExplainerApp_WebApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end