classdef ModelExplainer_WebApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ImportModelMenu             matlab.ui.container.Menu
        MachineLearningMenu         matlab.ui.container.Menu
        DeepLearningMenu            matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        ModelExplainerLabel         matlab.ui.control.Label
        VariabletopredictLabel      matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        ModelTab                    matlab.ui.container.Tab
        GridLayout12                matlab.ui.container.GridLayout
        UITableModels               matlab.ui.control.Table
        VisualizeDataTab            matlab.ui.container.Tab
        GridLayout9                 matlab.ui.container.GridLayout
        DataTable                   matlab.ui.control.Table
        Global_InterpretabilityTab  matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        Panel                       matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        GridLayout10                matlab.ui.container.GridLayout
        DropDownPI                  matlab.ui.control.DropDown
        PredictorImportanceAxes     matlab.ui.control.UIAxes
        PDPPanel                    matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        PDP2                        matlab.ui.container.Panel
        ICEButton                   matlab.ui.control.StateButton
        Predictor2DropDown          matlab.ui.control.DropDown
        Predictor1DropDown          matlab.ui.control.DropDown
        PDPAxes                     matlab.ui.control.UIAxes
        Local_InterpretabilityTab   matlab.ui.container.Tab
        GridLayout6                 matlab.ui.container.GridLayout
        GAMRegressionPanel          matlab.ui.container.Panel
        GridLayout11                matlab.ui.container.GridLayout
        GAMAxes                     matlab.ui.control.UIAxes
        ShapleyPanel                matlab.ui.container.Panel
        GridLayout7                 matlab.ui.container.GridLayout
        SimulationsSpinner          matlab.ui.control.Spinner
        UseParallelButton           matlab.ui.control.StateButton
        ShapleyAxes                 matlab.ui.control.UIAxes
        LIMEPanel                   matlab.ui.container.Panel
        GridLayout8                 matlab.ui.container.GridLayout
        DropDownSimpleModelType     matlab.ui.control.DropDown
        PredictorsSlider            matlab.ui.control.Slider
        DistancemeasureDropDown     matlab.ui.control.DropDown
        LIMEAxes                    matlab.ui.control.UIAxes
        DataTableLocal              matlab.ui.control.Table
        Switch                      matlab.ui.control.Switch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        myView      = mvc.View("app", app);
        myCallbacks = mvc.Callbacks("view", myView);
        h           = findobj(app.UIFigure, '-property', 'FontName');
        arrayfun(@(x) set(x, "FontName", "AkzidenzGrotesk"), h)
        % JUST TEST SOURCE CONTROL ON MLAPP
        test = 2+2;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportModelMenu = uimenu(app.UIFigure);
            app.ImportModelMenu.Text = 'Import Model';
            app.MachineLearningMenu = uimenu(app.ImportModelMenu);
            app.MachineLearningMenu.Text = 'Machine Learning';
            app.DeepLearningMenu = uimenu(app.ImportModelMenu);
            app.DeepLearningMenu.Text = 'Deep Learning';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Switch = uiswitch(app.GridLayout, 'slider');
            app.Switch.Items = {'Training', 'Testing'};
            app.Switch.Value = 'Training';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.GridLayout12 = uigridlayout(app.ModelTab);
            app.UITableModels = uitable(app.GridLayout12);
            app.UITableModels.ColumnName = {'Name'; 'Type'; 'Data'; 'Accuracy_Training'; 'Accuracy_Test'};
            app.UITableModels.RowName = {};
            app.UITableModels.Tooltip = {'Accuracy for regression model is RMSE/mean(data)'};
            app.VisualizeDataTab = uitab(app.TabGroup);
            app.VisualizeDataTab.Title = 'Visualize Data';
            app.GridLayout9 = uigridlayout(app.VisualizeDataTab);
            app.DataTable = uitable(app.GridLayout9);
            app.DataTable.ColumnName = '';
            app.DataTable.RowName = {};
            app.DataTable.ColumnSortable = true;
            app.Global_InterpretabilityTab = uitab(app.TabGroup);
            app.Global_InterpretabilityTab.Title = 'Global_Interpretability';
            app.GridLayout2 = uigridlayout(app.Global_InterpretabilityTab);
            app.PDPPanel = uipanel(app.GridLayout2);
            app.PDPPanel.Title = 'PDP';
            app.GridLayout3 = uigridlayout(app.PDPPanel);
            app.PDPAxes = uiaxes(app.GridLayout3);
            title(app.PDPAxes, 'Title')
            xlabel(app.PDPAxes, 'X')
            ylabel(app.PDPAxes, 'Y')
            zlabel(app.PDPAxes, 'Z')
            app.Predictor1DropDown = uidropdown(app.GridLayout3);
            app.Predictor2DropDown = uidropdown(app.GridLayout3);
            app.ICEButton = uibutton(app.GridLayout3, 'state');
            app.ICEButton.Text = 'ICE';
            app.PDP2 = uipanel(app.GridLayout3);
            app.Panel = uipanel(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.Panel);
            app.GridLayout10 = uigridlayout(app.GridLayout5);
            app.PredictorImportanceAxes = uiaxes(app.GridLayout10);
            title(app.PredictorImportanceAxes, 'Title')
            xlabel(app.PredictorImportanceAxes, 'X')
            ylabel(app.PredictorImportanceAxes, 'Y')
            zlabel(app.PredictorImportanceAxes, 'Z')
            app.DropDownPI = uidropdown(app.GridLayout10);
            app.DropDownPI.Items = {'Predictor_Importance', 'OOB_Permuted', 'MRMR', 'NCA'};
            app.DropDownPI.Value = 'Predictor_Importance';
            app.Local_InterpretabilityTab = uitab(app.TabGroup);
            app.Local_InterpretabilityTab.Title = 'Local_Interpretability';
            app.GridLayout6 = uigridlayout(app.Local_InterpretabilityTab);
            app.DataTableLocal = uitable(app.GridLayout6);
            app.DataTableLocal.ColumnName = '';
            app.DataTableLocal.RowName = {};
            app.DataTableLocal.ColumnSortable = true;
            app.LIMEPanel = uipanel(app.GridLayout6);
            app.LIMEPanel.Title = 'LIME';
            app.GridLayout8 = uigridlayout(app.LIMEPanel);
            app.LIMEAxes = uiaxes(app.GridLayout8);
            title(app.LIMEAxes, 'LIME')
            xlabel(app.LIMEAxes, 'X')
            ylabel(app.LIMEAxes, 'Y')
            zlabel(app.LIMEAxes, 'Z')
            app.DistancemeasureDropDown = uidropdown(app.GridLayout8);
            app.DistancemeasureDropDown.Items = {'chebychev', 'euclidean'};
            app.DistancemeasureDropDown.Value = 'chebychev';
            app.PredictorsSlider = uislider(app.GridLayout8);
            app.PredictorsSlider.MinorTicks = [];
            app.PredictorsSlider.Tooltip = {'Number of predictors'};
            app.PredictorsSlider.Value = 31.1652458817462;
            app.DropDownSimpleModelType = uidropdown(app.GridLayout8);
            app.DropDownSimpleModelType.Items = {'tree', 'linear'};
            app.DropDownSimpleModelType.Value = 'tree';
            app.ShapleyPanel = uipanel(app.GridLayout6);
            app.ShapleyPanel.Title = 'Shapley';
            app.GridLayout7 = uigridlayout(app.ShapleyPanel);
            app.ShapleyAxes = uiaxes(app.GridLayout7);
            title(app.ShapleyAxes, 'ShapleyValues')
            xlabel(app.ShapleyAxes, 'X')
            ylabel(app.ShapleyAxes, 'Y')
            zlabel(app.ShapleyAxes, 'Z')
            app.UseParallelButton = uibutton(app.GridLayout7, 'state');
            app.UseParallelButton.Text = 'UseParallel';
            app.SimulationsSpinner = uispinner(app.GridLayout7);
            app.SimulationsSpinner.Limits = [2 Inf];
            app.SimulationsSpinner.Value = 100;
            app.GAMRegressionPanel = uipanel(app.GridLayout6);
            app.GAMRegressionPanel.Title = 'GAM (Regression)';
            app.GridLayout11 = uigridlayout(app.GAMRegressionPanel);
            app.GAMAxes = uiaxes(app.GridLayout11);
            title(app.GAMAxes, 'GAM')
            xlabel(app.GAMAxes, 'X')
            ylabel(app.GAMAxes, 'Y')
            zlabel(app.GAMAxes, 'Z')
            app.VariabletopredictLabel = uilabel(app.GridLayout);
            app.VariabletopredictLabel.Text = '';
            app.ModelExplainerLabel = uilabel(app.GridLayout);
            app.ModelExplainerLabel.Text = {'Model Explainer'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelExplainer_WebApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end